classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Player1Pic             matlab.ui.control.Image
        Player2Pic             matlab.ui.control.Image
        Banked2                matlab.ui.container.Panel
        BankedSlot1_2          matlab.ui.control.Image
        BankedSlot2_2          matlab.ui.control.Image
        BankedSlot3_2          matlab.ui.control.Image
        BankedSlot4_2          matlab.ui.control.Image
        BankedSlot5_2          matlab.ui.control.Image
        BankedSlot6_2          matlab.ui.control.Image
        ScoringGuide           matlab.ui.container.Panel
        TextArea               matlab.ui.control.TextArea
        TextAreaLabel          matlab.ui.control.Label
        Scores                 matlab.ui.container.Panel
        Player2Score           matlab.ui.control.NumericEditField
        Player2EditFieldLabel  matlab.ui.control.Label
        Player1Score           matlab.ui.control.NumericEditField
        Player1EditFieldLabel  matlab.ui.control.Label
        Banked1                matlab.ui.container.Panel
        BankedSlot1            matlab.ui.control.Image
        BankedSlot2            matlab.ui.control.Image
        BankedSlot3            matlab.ui.control.Image
        BankedSlot4            matlab.ui.control.Image
        BankedSlot5            matlab.ui.control.Image
        BankedSlot6            matlab.ui.control.Image
        Rolled                 matlab.ui.container.Panel
        RolledSlot1            matlab.ui.control.Image
        RolledSlot2            matlab.ui.control.Image
        RolledSlot3            matlab.ui.control.Image
        RolledSlot4            matlab.ui.control.Image
        RolledSlot5            matlab.ui.control.Image
        RolledSlot6            matlab.ui.control.Image
        BankScoreButton        matlab.ui.control.Button
        RollDiceButton         matlab.ui.control.Button
    end

    %hi%
% Azan % 
%push psuh push baby%
    %{
    properties (Access = public)
        DiceArray1 % Description
    end
    
    methods (Access = public)
        
        function results = func(app)    % RENAME FUNCTION, INPUT, OUTPUT
            if numel(app.DiceArray1) == 6
                if unique(app.DiceArray1) == 1
                    app.Player1Score = app.Player1Score + 3000;  % 6 of any number scenario
                elseif unique(app.DiceArray1) == 2  % Possibilities are: 5&1, 4&2, 3&3
                   uniqueIndex = unique(app.DiceArray1);
                   if sum(uniqueIndex(1)) == 5 || sum(uniqueIndex(2)) == 5  % 5 of a kind + 1 single scenario
                       app.Player1Score = app.Player1Score + 2000;  % 5 of a kind points
                       if sum(app.DiceArray == 1) == 1  % Single 1 scenario
                           app.Player1Score = app.Player1Score + 100;   % Single 1 points
                       elseif sum(app.DiceArray == 5) == 1  % Single 5 scenario
                           app.Player1Score = app.Player1Score + 50;    % Single 5 points
                       elseif sum(app.DiceArray == 2) == 1  % Single 2 scenario
                           app.Player1Score = app.Player1Score; % No points for single 2
                       elseif sum(app.DiceArray == 3) == 1  % Single 3 scenario
                           app.Player1Score = app.Player1Score; % No points for single 3
                       elseif sum(app.DiceArray1 == 4) == 1 % Single 4 scenario
                           app.Player1Score = app.Player1Score; % No points for single 4
                       end
                   elseif sum(uniqueIndex(1)) == 4 || sum(uniqueIndex(2)) == 4
                       app.Player1Score = app.Player1Score + 1500;  % 4 of a kind + pair scenario
                   elseif sum(uniqueIndex(1)) == 3 && sum(uniqueIndex(2)) == 3
                       app.Player1Score = app.Player1Score + 2500;  % 2 triplets scenario
                   end
                elseif unique(app.DiceArray1) == 3  % 3 pairs scenario
                    app.Player1Score = app.Player1Score + 1500;
                elseif unique(app.DiceArray1) == 4  % Possibilities are: 2 pairs & 2 singles, triplet & 3 singles
                    % CONTINUE HERE
                elseif unique(app.DiceArray1) == 6
                    app.Player1Score = app.Player1Score + 1500;  % 1, 2, 3, 4, 5, & 6 scenario 

        end
    end
    %}

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DiceArray1 = zeros(1, 6);   % Initializes a 1x6 row array to input dice values into
        end

        % Button pushed function: RollDiceButton
        function RollDiceButtonPushed(app, event)
            rolledDice = randi(6, 1, 6);
            display(rolledDice);
           
       [y, fs] = audioread('rollingDice.mp3'); % This part of the code pulls from the diretpry and calls the file
    sound(y, fs); %plays the sound that was called above
            % path = 'dice_' + string(rolledDice(1)) + '.png';
            % disp(path);
            app.RolledSlot1.ImageSource = 'dice_' + string(rolledDice(1)) + '.png'; %This line of code gerneates the random dice in the first slot. It pulls the pictures from the saved data base and uses the line of code abode to generates the random number. 
            
            app.RolledSlot2.ImageSource = 'dice_' + string(rolledDice(2)) + '.png';
            
            app.RolledSlot3.ImageSource = 'dice_' + string(rolledDice(3)) + '.png';
          
            app.RolledSlot4.ImageSource = 'dice_' + string(rolledDice(4)) + '.png';
           
            app.RolledSlot5.ImageSource = 'dice_' + string(rolledDice(5)) + '.png';
          
            app.RolledSlot6.ImageSource = 'dice_' + string(rolledDice(6)) + '.png';
        end

        % Image clicked function: RolledSlot1
        function RolledSlot1ImageClicked(app, event)
            app.RolledSlot1.Visible = 'off';
            app.BankedSlot1.ImageSource = app.RolledSlot1.ImageSource;
            Die1String = string(app.RolledSlot1.ImageSource);

           [y, fs] = audioread('woosh_sound.mp3'); % Replace with your actual file name
    sound(y, fs); 

            Die1ValOnly = erase(Die1String, "dice_"); Die1ValOnly = erase(Die1ValOnly, ".png");
            Die1ValDouble = double(Die1ValOnly);
            app.DiceArray1(1) = Die1ValDouble;
          
 
        end

        % Image clicked function: RolledSlot2
        function RolledSlot2ImageClicked(app, event)
            app.RolledSlot2.Visible = 'off';
            app.BankedSlot2.ImageSource = app.RolledSlot2.ImageSource;
            Die2String = string(app.RolledSlot2.ImageSource);
            
               [y, fs] = audioread('woosh_sound.mp3'); % Replace with your actual file name
    sound(y, fs); 

            
            Die2ValOnly = erase(Die2String, "dice_"); Die2ValOnly = erase(Die2ValOnly, ".png");
            Die2ValDouble = double(Die2ValOnly);
            app.DiceArray1(2) = Die2ValDouble;
        end

        % Image clicked function: RolledSlot3
        function RolledSlot3ImageClicked(app, event)
            app.RolledSlot3.Visible = 'off';
            app.BankedSlot3.ImageSource = app.RolledSlot3.ImageSource;
           
        Die3String = string(app.RolledSlot3.ImageSource);
           
           [y, fs] = audioread('woosh_sound.mp3'); % Replace with your actual file name
    sound(y, fs); 

            
            Die3ValOnly = erase(Die3String, "dice_"); Die3ValOnly = erase(Die3ValOnly, ".png");
            Die3ValDouble = double(Die3ValOnly);
            app.DiceArray1(3) = Die3ValDouble;
        end

        % Image clicked function: RolledSlot4
        function RolledSlot4ImageClicked(app, event)
            app.RolledSlot4.Visible = 'off';
            app.BankedSlot4.ImageSource = app.RolledSlot4.ImageSource;
            Die4String = string(app.RolledSlot4.ImageSource);
            
            [y, fs] = audioread('woosh_sound.mp3'); % Replace with your actual file name
    sound(y, fs); 

            
            Die4ValOnly = erase(Die4String, "dice_"); Die4ValOnly = erase(Die4ValOnly, ".png");
            Die4ValDouble = double(Die4ValOnly);
            app.DiceArray1(4) = Die4ValDouble;
        end

        % Image clicked function: RolledSlot5
        function RolledSlot5ImageClicked(app, event)
            app.RolledSlot5.Visible = 'off';
            app.BankedSlot5.ImageSource = app.RolledSlot5.ImageSource;
            Die5String = string(app.RolledSlot5.ImageSource);
             
            [y, fs] = audioread('woosh_sound.mp3'); % Replace with your actual file name
    sound(y, fs); 


            Die5ValOnly = erase(Die5String, "dice_"); Die5ValOnly = erase(Die5ValOnly, ".png");
            Die5ValDouble = double(Die5ValOnly);
            app.DiceArray1(5) = Die5ValDouble;
        end

        % Image clicked function: RolledSlot6
        function RolledSlot6ImageClicked(app, event)
            app.RolledSlot6.Visible = 'off';
            app.BankedSlot6.ImageSource = app.RolledSlot6.ImageSource;
            Die6String = string(app.RolledSlot6.ImageSource);
           
            [y, fs] = audioread('woosh_sound.mp3'); % Calls the given sound from our dirtory
    sound(y, fs); %initiate the woosh sound when you click to bak the dice


            Die6ValOnly = erase(Die6String, "dice_"); Die6ValOnly = erase(Die6ValOnly, ".png"); 
            Die6ValDouble = double(Die6ValOnly);
            app.DiceArray1(6) = Die6ValDouble;
        end

        % Button pushed function: BankScoreButton
        function BankScoreButtonPushed(app, event)
            app.DiceArray1 = nonzeros(app.DiceArray1);  % Removes any empty slots from the array before scoring
            display(app.DiceArray1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RollDiceButton = uibutton(app.UIFigure, 'push');
            app.RollDiceButton.ButtonPushedFcn = createCallbackFcn(app, @RollDiceButtonPushed, true);
            app.RollDiceButton.Text = 'Roll Dice';
            app.BankScoreButton = uibutton(app.UIFigure, 'push');
            app.BankScoreButton.ButtonPushedFcn = createCallbackFcn(app, @BankScoreButtonPushed, true);
            app.BankScoreButton.Text = 'Bank Score';
            app.Rolled = uipanel(app.UIFigure);
            app.RolledSlot6 = uiimage(app.Rolled);
            app.RolledSlot6.ImageClickedFcn = createCallbackFcn(app, @RolledSlot6ImageClicked, true);
            app.RolledSlot5 = uiimage(app.Rolled);
            app.RolledSlot5.ImageClickedFcn = createCallbackFcn(app, @RolledSlot5ImageClicked, true);
            app.RolledSlot4 = uiimage(app.Rolled);
            app.RolledSlot4.ImageClickedFcn = createCallbackFcn(app, @RolledSlot4ImageClicked, true);
            app.RolledSlot3 = uiimage(app.Rolled);
            app.RolledSlot3.ImageClickedFcn = createCallbackFcn(app, @RolledSlot3ImageClicked, true);
            app.RolledSlot2 = uiimage(app.Rolled);
            app.RolledSlot2.ImageClickedFcn = createCallbackFcn(app, @RolledSlot2ImageClicked, true);
            app.RolledSlot1 = uiimage(app.Rolled);
            app.RolledSlot1.ImageClickedFcn = createCallbackFcn(app, @RolledSlot1ImageClicked, true);
            app.Banked1 = uipanel(app.UIFigure);
            app.BankedSlot6 = uiimage(app.Banked1);
            app.BankedSlot5 = uiimage(app.Banked1);
            app.BankedSlot4 = uiimage(app.Banked1);
            app.BankedSlot3 = uiimage(app.Banked1);
            app.BankedSlot2 = uiimage(app.Banked1);
            app.BankedSlot1 = uiimage(app.Banked1);
            app.Scores = uipanel(app.UIFigure);
            app.Scores.Title = 'Scores';
            app.Player1EditFieldLabel = uilabel(app.Scores);
            app.Player1EditFieldLabel.Text = 'Player 1';
            app.Player1Score = uieditfield(app.Scores, 'numeric');
            app.Player1Score.Editable = 'off';
            app.Player2EditFieldLabel = uilabel(app.Scores);
            app.Player2EditFieldLabel.Text = 'Player 2';
            app.Player2Score = uieditfield(app.Scores, 'numeric');
            app.Player2Score.Editable = 'off';
            app.ScoringGuide = uipanel(app.UIFigure);
            app.ScoringGuide.Title = 'Scoring Guide';
            app.TextAreaLabel = uilabel(app.ScoringGuide);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.ScoringGuide);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Single 1 = 100 pts.'; 'Single 5 = 50 pts.'; 'Three 1s = 300 pts.'; 'Three 2s = 200 pts.'; 'Three 3s = 300 pts.'; 'Three 4s = 400 pts.'; 'Three 5s = 500 pts.'; 'Three 6s = 600 pts.'; 'Four of a Kind = 1,000 pts.'; 'Five of a Kind = 2,000 pts.'; 'Six of a Kind = 3,000 pts.'; '1, 2, 3, 4, 5, 6 = 1,500 pts.'; 'Three Pairs = 1,500 pts.'; 'Four of a Kind w/ a Pair = 1,500 pts.'; 'Two Triplets = 2,500 pts.'};
            app.Banked2 = uipanel(app.UIFigure);
            app.BankedSlot6_2 = uiimage(app.Banked2);
            app.BankedSlot5_2 = uiimage(app.Banked2);
            app.BankedSlot4_2 = uiimage(app.Banked2);
            app.BankedSlot3_2 = uiimage(app.Banked2);
            app.BankedSlot2_2 = uiimage(app.Banked2);
            app.BankedSlot1_2 = uiimage(app.Banked2);
            app.Player2Pic = uiimage(app.UIFigure);
            app.Player2Pic.ImageSource = fullfile(pathToMLAPP, 'Player2.png');
            app.Player1Pic = uiimage(app.UIFigure);
            app.Player1Pic.ImageSource = fullfile(pathToMLAPP, 'Player1.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end